classdef log_parse_merge < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        chkbFileName           matlab.ui.control.CheckBox
        saveAsName             matlab.ui.control.EditField
        lblMergePath           matlab.ui.control.Label
        btnMergeFiles          matlab.ui.control.Button
        SaveFileAsLabel        matlab.ui.control.Label
        chkbRadioENB           matlab.ui.control.CheckBox
        chkbRadioUE            matlab.ui.control.CheckBox
        chkbIperfClient        matlab.ui.control.CheckBox
        chkbIperfServer        matlab.ui.control.CheckBox
        chkbVehicleOut         matlab.ui.control.CheckBox
        lblradioENBCSV         matlab.ui.control.Label
        lblradioUECSV          matlab.ui.control.Label
        lblIperfClientCSV      matlab.ui.control.Label
        lblIperfServerCSV      matlab.ui.control.Label
        lblVehicleOutCSV       matlab.ui.control.Label
        lblradioENBPath        matlab.ui.control.Label
        lblradioUEPath         matlab.ui.control.Label
        lblIperfClientPath     matlab.ui.control.Label
        lblIperfServerPath     matlab.ui.control.Label
        lblVehicleOutPath      matlab.ui.control.Label
        radioENBLabel          matlab.ui.control.Label
        radioUELabel           matlab.ui.control.Label
        IperfClientLabel       matlab.ui.control.Label
        IperfServerLabel       matlab.ui.control.Label
        vehicleOutLabel        matlab.ui.control.Label
        btnradioENBConvert     matlab.ui.control.Button
        btnradioENBPath        matlab.ui.control.Button
        btnradioUEConvert      matlab.ui.control.Button
        btnradioUEPath         matlab.ui.control.Button
        btnIperfClientConvert  matlab.ui.control.Button
        btnIperfClientPath     matlab.ui.control.Button
        btnIperfServerConvert  matlab.ui.control.Button
        btnIperfServerPath     matlab.ui.control.Button
        btnVehicleOutConvert   matlab.ui.control.Button
        btnVehicleOutPath      matlab.ui.control.Button
        Label                  matlab.ui.control.Label
    end

    
    properties (Access = public)
        vehicleOutReady = false; % Description
        vehicleOutFile;
        vehicleOutPath;
        vehicleOutComplete = false;

        iperfServerReady = false;
        iperfServerFile;
        iperfServerPath;
        iperfServerComplete = false;

        iperfClientReady = false;
        iperfClientFile;
        iperfClientPath;
        iperfClientComplete = false;

        radioUEReady = false;
        radioUEFile;
        radioUEPath;
        radioUEComplete = false;

        radioENBReady = false;
        radioENBFile;
        radioENBPath;
        radioENBComplete = false;

        fileNameComplete = false;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnVehicleOutPath
        function btnVehicleOutPathButtonPushed(app, event)
            [app.vehicleOutFile,app.vehicleOutPath] = uigetfile('*.txt','Select the proper log text file.','vehicleOut');
            % Check if a valid filepath. If so then populate the textbox.
            if app.vehicleOutPath ~= 0
                %app.filePath = append(path, file);
                app.lblVehicleOutPath.Text = append(app.vehicleOutPath, app.vehicleOutFile);
                app.vehicleOutReady = true;
            else
                % User did not select a proper file.
                app.lblVehicleOutPath.Text = 'Invalid File';
                app.vehicleOutReady = false;
            end
        end

        % Button pushed function: btnVehicleOutConvert
        function btnVehicleOutConvertButtonPushed(app, event)
            if(app.vehicleOutReady)
                % vehicleLog Parse Code.

                % vehicleOut parsing. This function is called after the application has
                % determined that the log file is vehicleOut. This log file is relatively
                % simple since there are no error messages printed.
                
                fid = fopen(append(app.vehicleOutPath, app.vehicleOutFile)); % Open log file
                
                line = fgetl(fid);  % Get out the first line of the file.
                vehicleOutCell = transpose(split(line, ',')); % vehicleOutCell has number values and datetime
                                                              % at the seventh index.
                timeSplit = transpose(split(vehicleOutCell(7), '.')); % Remove millisecond from datetime.
                vehicleOutCell = str2double(vehicleOutCell);    % Convert numbers to double. datetime will be NaN.
                t_temp = datetime(timeSplit(1), 'InputFormat', 'yyyy-MM-dd HH:mm:ss'); % Next three lines convert
                t_temp.TimeZone = 'America/New_York';                                  % datetime to unix timestamp.
                vehicleOutCell(7) = posixtime(t_temp);                                 % Put timestamp back in index 7.
                
                % The same process is done in the while loop for each line in the text
                % file, until the end of file is reached. Each line is transformed into a
                % row of cells that are then appenended to a cell matrix.
                while ~feof(fid)
                    line = fgetl(fid);
                    tlineTemp = transpose(split(line, ','));
                    timeSplit = transpose(split(tlineTemp(7), '.'));
                    tlineTemp(7) = timeSplit(1);
                    tlineDouble = str2double(tlineTemp);
                    t_temp = datetime(timeSplit(1), 'InputFormat', 'yyyy-MM-dd HH:mm:ss');
                    t_temp.TimeZone = 'America/New_York';
                    tlineDouble(7) = posixtime(t_temp);
                
                    vehicleOutCell = [vehicleOutCell ; tlineDouble];    
                end
                
                % The cell matrix is complete. Convert to a table and add column headers.
                vehicleOutTable = array2table(vehicleOutCell, "VariableNames", ["Number" "Longitude" "Latitude" ...
                    "Altitude" "Voltage" "Battery Level" "Time" "Fix" ...
                    "Number of Satellites"]);
                
                newCSVFileName = app.vehicleOutFile(1 : end-4);
                newCSVFileName = append(newCSVFileName, ".csv");
                % Data is now prepared to be converted to a comma seperated value file.
                writetable(vehicleOutTable,append(app.vehicleOutPath, newCSVFileName),"Delimiter",",")
                
                fclose(fid);

                app.lblVehicleOutCSV.Text = append(app.vehicleOutPath, newCSVFileName);
                app.vehicleOutComplete = true;
                app.chkbVehicleOut.Value = true;
                if app.vehicleOutComplete && app.iperfServerComplete && app.iperfClientComplete && app.radioUEComplete && app.radioENBComplete && app.fileNameComplete
                    app.btnMergeFiles.Enable = true;
                end
            else
                app.lblVehicleOutCSV.Text = "Must select a text file first.";
                app.vehicleOutComplete = false;
                app.chkbVehicleOut.Value = false;
            end
        end

        % Button pushed function: btnIperfServerPath
        function btnIperfServerPathButtonPushed(app, event)
            [app.iperfServerFile,app.iperfServerPath] = uigetfile('*.txt','Select the proper log text file.','iperfServer');
            % Check if a valid filepath. If so then populate the textbox.
            if app.iperfServerPath ~= 0
                %app.filePath = append(path, file);
                app.lblIperfServerPath.Text = append(app.iperfServerPath, app.iperfServerFile);
                app.iperfServerReady = true;
            else
                % User did not select a proper file.
                app.lblIperfServerPath.Text = 'Invalid File';
                app.iperfServerReady = false;
            end
        end

        % Button pushed function: btnIperfServerConvert
        function btnIperfServerConvertButtonPushed(app, event)
                if(app.iperfServerReady)
                    % iperfServer Parse Code.
                    % iperfServer parsing. This function is called after the application has
                    % determined that the log file is iperfserver.
                    
                    fid = fopen(append(app.iperfServerPath, app.iperfServerFile)); % Open log file
                    
                    time_num_list = [];
                    id_num_list = [];
                    interval_start = [];
                    interval_end = [];
                    transfer_num_list = [];
                    bandwidth_num_list = [];
                    
                    line = fgetl(fid);  % Get out the first line of the file.
                    
                    % We bodly assume that the first line is not data as the server always
                    % prints a listening comment.
                    
                    % The same process is done in the while loop for each line in the text
                    % file, until the end of file is reached. Each line is transformed into a
                    % row of cells that are then appenended to a cell matrix.
                    while ~feof(fid)
                        line = fgetl(fid);
                        tlineTemp = transpose(split(line, '['));
                        timeSplit = split(tlineTemp(2), '.');   % Remove millisecond from datetime.
                        t_temp = datetime(timeSplit(1), 'InputFormat', 'yyyy-MM-dd HH:mm:ss'); % Next three lines convert
                        t_temp.TimeZone = 'America/New_York';                                  % datetime to unix timestamp.
                        if length(tlineTemp) == 3
                            tlineTemp = transpose(split(tlineTemp(3), ']'));
                            id_temp = str2double(tlineTemp(1));
                            tlineTemp = transpose(split(tlineTemp(2), 'sec'));
                            interval_temp = split(tlineTemp(1), '-');
                            if length(tlineTemp) == 3
                                %disp(id_temp)
                                %disp(tlineTemp)
                                transfer_temp = transpose(split(tlineTemp(2), 'Bytes'));
                                bandwidth_temp = transpose(split(transfer_temp(2), 'bits'));
                                %disp(transfer_temp(1));
                                transfer_str = char(transfer_temp(1));
                                transfer_unit = transfer_str(end);
                                bandwidth_str = char(bandwidth_temp(1));
                                bandwidth_unit = bandwidth_str(end);
                                %disp(transfer_str)
                                %disp(transfer_unit)
                                switch transfer_unit
                                    case 'M'
                                        % Split M, then convert to double.
                                        transfer_str_split = split(transfer_str, 'M');
                                        transfer_num_list = [transfer_num_list ; str2double(transfer_str_split(1))];
                                    case 'K'
                                        % Split K, then convert to double.
                                        transfer_str_split = split(transfer_str, 'K');
                                        transfer_num_list = [transfer_num_list ; str2double(transfer_str_split(1))/1000];
                                    otherwise
                                        % Just convert to double.
                                        transfer_num_list = [transfer_num_list ; str2double(transfer_str)];
                                end
                    
                                switch bandwidth_unit
                                    case 'M'
                                        % Split M, then convert to double.
                                        bandwidth_str_split = split(bandwidth_str, 'M');
                                        bandwidth_num_list = [bandwidth_num_list ; str2double(bandwidth_str_split(1))];
                                    case 'K'
                                        % Split K, then convert to double.
                                        bandwidth_str_split = split(bandwidth_str, 'K');
                                        bandwidth_num_list = [bandwidth_num_list ; str2double(bandwidth_str_split(1))/1000];
                                    otherwise
                                        % Just convert to double.
                                        bandwidth_num_list = [bandwidth_num_list ; str2double(bandwidth_str)];
                                end
                                id_num_list = [id_num_list ; id_temp];
                                time_num_list = [time_num_list ; posixtime(t_temp)];
                                interval_start = [interval_start ; str2double(interval_temp(1))];
                                interval_end = [interval_end ; str2double(interval_temp(2))];
                                % Test switch case.
                                % Split and keep time interval.
                            end
                        end   
                    end
                    
                    iperfServerArray = [time_num_list id_num_list interval_start interval_end transfer_num_list bandwidth_num_list];
                    columnNames = ["Time","Iperf Svr ID","Iperf Svr Interval Start [sec]", "Iperf Svr Interval End [sec]", "Iperf Svr Transfer [MB]", "Iperf Svr Bandwidth [Mbps]"];
                    iperfServerTable = array2table(iperfServerArray, "VariableNames", columnNames);
                    iperfServerTable(end,:) = []; % The last 2 rows are the total sent by sender and received by reciever.
                    iperfServerTable(end,:) = [];

                    newCSVFileName = app.iperfServerFile(1 : end-4);
                    newCSVFileName = append(newCSVFileName, ".csv");
                    % Data is now prepared to be converted to a comma seperated value file.
                    writetable(iperfServerTable,append(app.iperfServerPath, newCSVFileName),"Delimiter",",")                    
                    
                    fclose(fid);

                    app.lblIperfServerCSV.Text = append(app.iperfServerPath, newCSVFileName);
                    app.iperfServerComplete = true;
                    app.chkbIperfServer.Value = true;
                    if app.vehicleOutComplete && app.iperfServerComplete && app.iperfClientComplete && app.radioUEComplete && app.radioENBComplete && app.fileNameComplete
                        app.btnMergeFiles.Enable = true;
                    end
                else
                    app.lblIperfServerCSV.Text = "Must select a text file first.";
                    app.iperfServerComplete = false;
                    app.chkbIperfServer.Value = false;
                end
        end

        % Button pushed function: btnIperfClientPath
        function btnIperfClientPathButtonPushed(app, event)
            [app.iperfClientFile,app.iperfClientPath] = uigetfile('*.txt','Select the proper log text file.','iperfClient');
            % Check if a valid filepath. If so then populate the textbox.
            if app.iperfClientPath ~= 0
                %app.filePath = append(path, file);
                app.lblIperfClientPath.Text = append(app.iperfClientPath, app.iperfClientFile);
                app.iperfClientReady = true;
            else
                % User did not select a proper file.
                app.lblIperfClientPath.Text = 'Invalid File';
                app.iperfClientReady = false;
            end           
        end

        % Button pushed function: btnradioUEPath
        function btnradioUEPathButtonPushed(app, event)
            [app.radioUEFile,app.radioUEPath] = uigetfile('*.txt','Select the proper log text file.','radioUE');
            % Check if a valid filepath. If so then populate the textbox.
            if app.radioUEPath ~= 0
                %app.filePath = append(path, file);
                app.lblradioUEPath.Text = append(app.radioUEPath, app.radioUEFile);
                app.radioUEReady = true;
            else
                % User did not select a proper file.
                app.lblradioUEPath.Text = 'Invalid File';
                app.radioUEReady = false;
            end     
        end

        % Button pushed function: btnradioENBPath
        function btnradioENBPathButtonPushed(app, event)
            [app.radioENBFile,app.radioENBPath] = uigetfile('*.txt','Select the proper log text file.','radioENB');
            % Check if a valid filepath. If so then populate the textbox.
            if app.radioENBPath ~= 0
                %app.filePath = append(path, file);
                app.lblradioENBPath.Text = append(app.radioENBPath, app.radioENBFile);
                app.radioENBReady = true;
            else
                % User did not select a proper file.
                app.lblradioENBPath.Text = 'Invalid File';
                app.radioENBReady = false;
            end                
        end

        % Button pushed function: btnIperfClientConvert
        function btnIperfClientConvertButtonPushed(app, event)
            if(app.iperfClientReady)
                % iperfClient Parse Code.
                % iperfClient parsing. This function is called after the application has
                % determined that the log file is iperfserver.
                
                fid = fopen(append(app.iperfClientPath, app.iperfClientFile)); % Open log file
                
                time_num_list = [];
                id_num_list = [];
                interval_start = [];
                interval_end = [];
                transfer_num_list = [];
                bandwidth_num_list = [];
                retry_list = [];
                cwnd_list = [];
                
                line = fgetl(fid);  % Get out the first line of the file.
                
                % We bodly assume that the first line is not data as the server always
                % prints a listening comment.
                
                % The same process is done in the while loop for each line in the text
                % file, until the end of file is reached. Each line is transformed into a
                % row of cells that are then appenended to a cell matrix.
                while ~feof(fid)
                    line = fgetl(fid);
                    tlineTemp = transpose(split(line, '['));
                    timeSplit = split(tlineTemp(2), '.');   % Remove millisecond from datetime.
                    t_temp = datetime(timeSplit(1), 'InputFormat', 'yyyy-MM-dd HH:mm:ss'); % Next three lines convert
                    t_temp.TimeZone = 'America/New_York';                                  % datetime to unix timestamp.
                    if length(tlineTemp) == 3
                        tlineTemp = transpose(split(tlineTemp(3), ']'));
                        id_temp = str2double(tlineTemp(1));
                        tlineTemp = transpose(split(tlineTemp(2), 'sec'));
                        interval_temp = split(tlineTemp(1), '-');
                        if length(tlineTemp) == 3
                            %disp(id_temp)
                            %disp(tlineTemp)
                            transfer_temp = transpose(split(tlineTemp(2), 'Bytes'));
                            bandwidth_temp = transpose(split(transfer_temp(2), 'bits'));
                            transfer_str = char(transfer_temp(1));
                            transfer_unit = transfer_str(end);
                            bandwidth_str = char(bandwidth_temp(1));
                            bandwidth_unit = bandwidth_str(end);
                            %disp(transfer_str)
                            retr_cwnd = transpose(split(tlineTemp(3), "Bytes"));
                            cwnd_unit = char(retr_cwnd(1));
                            cwnd_unit = cwnd_unit(end);
                            retr_cwnd_num = str2double(regexp(char(retr_cwnd(1)), '(\d,)*\d+(\.\d*)?', 'Match'));
                            if length(retr_cwnd_num) ~= 2 % Ensures the data summary lines are not included.
                                continue
                            end
                            %disp(retr_cwnd_num)
                            switch transfer_unit
                                case 'M'
                                    % Split M, then convert to double.
                                    transfer_str_split = split(transfer_str, 'M');
                                    transfer_num_list = [transfer_num_list ; str2double(transfer_str_split(1))];
                                case 'K'
                                    % Split K, then convert to double.
                                    transfer_str_split = split(transfer_str, 'K');
                                    transfer_num_list = [transfer_num_list ; str2double(transfer_str_split(1))/1000];
                                otherwise
                                    % Just convert to double.
                                    transfer_num_list = [transfer_num_list ; str2double(transfer_str)];
                            end
                
                            switch bandwidth_unit
                                case 'M'
                                    % Split M, then convert to double.
                                    bandwidth_str_split = split(bandwidth_str, 'M');
                                    bandwidth_num_list = [bandwidth_num_list ; str2double(bandwidth_str_split(1))];
                                case 'K'
                                    % Split K, then convert to double.
                                    bandwidth_str_split = split(bandwidth_str, 'K');
                                    bandwidth_num_list = [bandwidth_num_list ; str2double(bandwidth_str_split(1))/1000];
                                otherwise
                                    % Just convert to double.
                                    bandwidth_num_list = [bandwidth_num_list ; str2double(bandwidth_str)];
                            end
                
                            switch cwnd_unit
                                case 'K'
                                    % Found K, so divide by 1000.
                                    retr_cwnd_num(2) = retr_cwnd_num(2) / 1000;
                                otherwise
                                    % Do nothing for K and empty
                            end
                            id_num_list = [id_num_list ; id_temp];
                            time_num_list = [time_num_list ; posixtime(t_temp)];
                            interval_start = [interval_start ; str2double(interval_temp(1))];
                            interval_end = [interval_end ; str2double(interval_temp(2))];
                            retry_list = [retry_list ; retr_cwnd_num(1)];
                            cwnd_list = [cwnd_list; retr_cwnd_num(2)];
                            % Test switch case.
                            % Split and keep time interval.
                        end
                    end   
                end
                
                iperfClientArray = [time_num_list id_num_list interval_start interval_end transfer_num_list bandwidth_num_list retry_list cwnd_list];
                columnNames = ["Time","Iperf Clt ID","Iperf Clt Interval Start [sec]", "Iperf Clt Interval End [sec]", "Iperf Clt Transfer [MB]", "Iperf Clt Bandwidth [Mbps]", "Iperf Clt Retr", "Iperf Clt Cwnd [MB]"];
                iperfClientTable = array2table(iperfClientArray, "VariableNames", columnNames);
                
                newCSVFileName = app.iperfClientFile(1 : end-4);
                newCSVFileName = append(newCSVFileName, ".csv");
                % Data is now prepared to be converted to a comma seperated value file.
                writetable(iperfClientTable,append(app.iperfClientPath, newCSVFileName),"Delimiter",",")
                
                fclose(fid);

                app.lblIperfClientCSV.Text = append(app.iperfClientPath, newCSVFileName);
                app.iperfClientComplete = true;
                app.chkbIperfClient.Value = true;
                if app.vehicleOutComplete && app.iperfServerComplete && app.iperfClientComplete && app.radioUEComplete && app.radioENBComplete && app.fileNameComplete
                    app.btnMergeFiles.Enable = true;
                end
            else
                app.lblIperfClientCSV.Text = "Must select a text file first.";
                app.iperfClientComplete = false;
                app.chkbIperfClient.Value = false;
            end
        end

        % Button pushed function: btnradioUEConvert
        function btnradioUEConvertButtonPushed(app, event)
            if(app.radioUEReady)
                % radioUE Parse Code.

                [fid,msg] = fopen(append(app.radioUEPath, app.radioUEFile),'r');                                         % open log file
                
                radio_ueData_out = [];
                radio_ueData = [];
                while ~feof(fid)
                
                    tline = fgetl(fid);                                                             % get first line
                    radio_ueCell = transpose(split(tline, ']'));                                   % splits off time componet
                    radio_ueData = transpose(split(radio_ueCell(2), ' '));                        % seperates elements based on spaces
                    test = transpose(split(radio_ueCell(2), ' '));
                    
                    radio_ueData = strrep(radio_ueData,' ','');                       % removes spaces from the elements
                    radio_ueData = radio_ueData(~cellfun('isempty',radio_ueData));   % removes empty elements from array
                    L = length(radio_ueData);
                    if(L > 5)
                        if(strcmp(radio_ueData(1),'cc') ~= 1)
                            if(strcmp(radio_ueData(6), '|') == 1)
                                radio_ueData(6) = [];                               % removes | from array elements 6, 12
                                radio_ueData(12) = [];
                            
                                cfo = radio_ueData(5);                              % this section pulls the cfo element and removes the m or u and converts to mili units
                                cfo_txt = char(cfo);
                                if(strcmp(cfo_txt(end),'m') == 1)
                                    cfo_txt(end) = [];
                                    cfo_txt = strjoin(string(cfo_txt),'');
                                    cfo_txt = cellstr(cfo_txt);
                                    radio_ueData(5) = cfo_txt(1);
                                elseif(strcmp(cfo_txt(end),'u') == 1)
                                    cfo_txt(end) = [];
                                    cfo_db = str2double(cfo_txt);
                                    cfo_db = cfo_db/1000;
                                    cfo_db = num2str(cfo_db);
                                    cfo_cell = cellstr(cfo_db);
                                    radio_ueData(5) = cfo_cell(1);
                                end
                            
                                dl_brate = radio_ueData(9);                         % this section pulls the brate and converts it to Mbs without the m or k char
                                dl_brate_txt = char(dl_brate);
                                if(strcmp(dl_brate_txt(end),'k') == 1)
                                    dl_brate_txt(end) = [];                         % removes kbs and then converts to Mbs
                                    dl_brate_db = str2double(dl_brate_txt);
                                    dl_brate_db = dl_brate_db/1000;
                                    dl_brate_db = num2str(dl_brate_db);
                                    dl_brate_db = cellstr(dl_brate_db);
                                    radio_ueData(9) = dl_brate_db(1);
                                elseif(strcmp(dl_brate_txt(end),'M') == 1)
                                    dl_brate_txt(end) = [];                         % if in Mbs, removes the M character
                                    dl_brate_db = cellstr(dl_brate_txt);
                                    radio_ueData(9) = dl_brate_db;
                                end
                            
                            
                                dl_bler = radio_ueData(10);
                                dl_bler_txt = char(dl_bler);
                                if(strcmp(dl_bler_txt(end),'%') == 1)
                                    dl_bler_txt(end) = [];
                                    dl_bler_txt = cellstr(dl_bler_txt);
                                    radio_ueData(10) = dl_bler_txt(1);
                                end
                            
                                ul_buff = radio_ueData(13);                         % this section checks the ul buff and removes the k
                                ul_buff_txt = char(ul_buff);
                                if(strcmp(ul_buff_txt(end),'k') == 1)
                                    ul_buff_txt(end) = [];                          % removes kbs and then converts to Mbs
                                    ul_buff_txt = strjoin(string(ul_buff_txt),'');
                                    ul_buff_db = cellstr(ul_buff_txt);
                                    %ul_buff_txt = num2cell(ul_buff_txt);
                                    radio_ueData(13) = ul_buff_db;
                            %         ul_buff_db = str2double(ul_buff_txt);         % currently is no
                            %                                                         need for kbs to Mbs or Mbs
                            %         ul_buff_db = ul_buff_db/1000;
                            %         ul_buff_db = num2str(ul_buff_db);
                            %         ul_buff_db = cellstr(ul_buff_db);
                            %         radio_ueData(13) = ul_buff_db(1);
                            %     elseif(strcmp(ul_buff_txt(end),'M') == 1)
                            %         ul_buff_txt(end) = [];                           % if in Mbs, removes the M character
                            %         ul_buff_db = cellstr(ul_buff_txt);
                            %         radio_ueData(13) = ul_buff_db;
                                end
                            
                            
                                ul_brate = radio_ueData(14);
                                ul_brate_txt = char(ul_brate);                                                % then converts and/or replaces the array element
                                test = ul_brate_txt(end);                                                
                                if (strcmp(ul_brate_txt(end),'k') == 1)
                                    ul_brate_txt(end) = [];
                                    ul_brate_db = str2double(ul_brate_txt);
                                    ul_brate_db = ul_brate_db/1000;
                                    ul_brate_mbs = num2str(ul_brate_db);
                                    ul_brate_mbs = cellstr(ul_brate_mbs);
                                    radio_ueData(14) = ul_brate_mbs(1);
                                elseif (strcmp(ul_brate_txt(end),'M') == 1)
                                    ul_brate_txt(end) = [];
                                    ul_brate_mbs = cellstr(ul_brate_txt);
                                    radio_ueData(14) = ul_brate_mbs;
                                end
                            
                            
                                ul_bler = radio_ueData(15);                                                 % removes the percent sign from the ul bler
                                ul_bler_txt = char(dl_bler);
                                if(strcmp(ul_bler_txt(end),'%') == 1)
                                    ul_bler_txt(end) = [];
                                    ul_bler_txt = cellstr(ul_bler_txt);
                                    radio_ueData(15) = ul_bler_txt(1);
                                end
                            
                                timeSplit = transpose(split(radio_ueCell(1), '['));
                                timeSplit1 = transpose(split(timeSplit(2), '.'));                   % Remove millisecond from datetime.
                                radio_ueData_db = str2double(radio_ueData);                          % 'lte' should be converted to NaN
                                t_temp = datetime(timeSplit1(1), 'InputFormat', 'yyyy-MM-dd HH:mm:ss');
                                t_temp.TimeZone = 'America/New_York';
                                time = posixtime(t_temp);
                                radio_ueData_db = [time, radio_ueData_db];
                                
                                radio_ueData_out = [radio_ueData_out ; radio_ueData_db];
                            
                            end
                        end
                    end
                end
                radio_ueTable = array2table(radio_ueData_out, "VariableNames", ["Time" "UE cc" "UE pci" "UE rsrp" ...
                    "UE pl" "UE cfo [mili]" "UE DL mcs" "UE DL snr" "UE DL iter_dl" ...
                    "UE DL brate [Mbps]" "UE DL bler [%]" "UE DL ta_us" "UE UL mcs" "UE UL buff [kilo]" "UE UL brate [Mbps]" "UE UL bler [%]"]);
                
                newCSVFileName = app.radioUEFile(1 : end-4);
                newCSVFileName = append(newCSVFileName, ".csv");
                writetable(radio_ueTable,append(app.radioUEPath, newCSVFileName),"Delimiter",",")
                
                fclose(fid);
                
                app.lblradioUECSV.Text = append(app.radioUEPath, newCSVFileName);
                app.radioUEComplete = true;
                app.chkbRadioUE.Value = true;
                if app.vehicleOutComplete && app.iperfServerComplete && app.iperfClientComplete && app.radioUEComplete && app.radioENBComplete && app.fileNameComplete
                    app.btnMergeFiles.Enable = true;
                end
            else
                app.lblradioUECSV.Text = "Must select a text file first.";
                app.radioUEComplete = false;
                app.chkbRadioUE.Value = false;
            end
        end

        % Button pushed function: btnradioENBConvert
        function btnradioENBConvertButtonPushed(app, event)
            if(app.radioENBReady)
                % radioENB Parse Code.

                [fid,msg] = fopen(append(app.radioENBPath, app.radioENBFile),'r');                                         % open log file
                
                tline = fgetl(fid);                                                             % get first line
                radio_enbCell = transpose(split(tline, ']'));                                   % splits off time componet
                radio_enbData = transpose(split(radio_enbCell(2), ' '));                        % seperates elements based on spaces
                test = transpose(split(radio_enbCell(2), ' '));
                radio_enbData = strrep(radio_enbData, '-----------------DL----------------|-------------------------UL-------------------------','');
                TF = isempty(radio_enbData);
                if TF == 0
                    if (strcmp(radio_enbData(2), 'lte') == 1)
                        radio_enbData = strrep(radio_enbData,' ','');                       % removes spaces from the elements
                        radio_enbData = radio_enbData(~cellfun('isempty',radio_enbData));   % removes empty elements from array
                        radio_enbData(10) = [];
                
                        kbs = radio_enbData(6);                                             % pulls the 6th array element
                        kbs_txt = char(kbs);
                        test = kbs_txt(end);
                        if (strcmp(kbs_txt(end),'k') == 1)                                  % checks if it is in kbs
                            kbs_txt(end) = [];                                              % removes kbs and then converts to Mbs
                            kbs_db = str2double(kbs_txt);
                            Mbs_db = kbs_db/1000;
                            Mbs = num2str(Mbs_db);
                            Mbs_cell6 = cellstr(Mbs);
                            radio_enbData(6) = Mbs_cell6(1);                                % puts the converted element back into array
                        elseif (strcmp(kbs_txt(end),'M') == 1)
                            kbs_txt(end) = [];                                              % if in Mbs, removes the M character
                            Mbs_cell6 = cellstr(kbs_txt);
                            radio_enbData(6) = Mbs_cell6;
                        end
                        kbs = radio_enbData(14);                                            % same process as above, checks for kbs or Mbs and
                        kbs_txt = char(kbs);                                                % then converts and/or replaces the array element
                        test = kbs_txt(end);                                                
                        if (strcmp(kbs_txt(end),'k') == 1)
                            kbs_txt(end) = [];
                            kbs_db = str2double(kbs_txt);
                            Mbs_db = kbs_db/1000;
                            Mbs = num2str(Mbs_db);
                            Mbs_cell14 = cellstr(Mbs);
                            radio_enbData(14) = Mbs_cell14(1);
                        elseif (strcmp(kbs_txt(end),'M') == 1)
                            kbs_txt(end) = [];
                            Mbs_cell14 = cellstr(kbs_txt);
                            radio_enbData(14) = Mbs_cell14;
                        end
                        
                        kbs = radio_enbData(18);                                            % checks if in kbs, same process for previous elements
                        kbs_txt = char(kbs);
                        test = kbs_txt(end);
                        if (strcmp(kbs_txt(end),'k') == 1)
                            kbs_txt(end) = [];
                            Mbs_cell = cellstr(kbs_txt);
                            radio_enbData(18) = Mbs_cell;
                        end
                
                        kbs = radio_enbData(17);                                            % pulls the 17th element
                        kbs_txt = char(kbs);
                        test = kbs_txt(end);
                        if (strcmp(kbs_txt(end),'%') == 1)                                  % checks to see if there is a percent sign
                                kbs_txt(end) = [];
                                Mbs_cell = cellstr(kbs_txt);
                                radio_enbData(17) = Mbs_cell;                               % removes percent and replaces element
                        end
                
                        kbs = radio_enbData(9);                                             % pulls 9th element
                        kbs_txt = char(kbs);
                        test = kbs_txt(end);
                        if (strcmp(kbs_txt(end),'%') == 1)                                  % checks to see if there is a percent
                                kbs_txt(end) = [];
                                Mbs_cell = cellstr(kbs_txt);
                                radio_enbData(9) = Mbs_cell;                                % removes percent and replaces the element
                        end
                
                        kbs = radio_enbData(10);                                            % pulls the 10th array element
                        kbs_txt = char(kbs);
                        test = kbs_txt;
                        if (strcmp(kbs_txt,'n/a') == 1)                                     % checks if the element is 'n/a'
                               kbs_txt = '0';
                               Mbs_cell = cellstr(kbs_txt);
                               radio_enbData(10) = Mbs_cell;                                % if true, replaces element with 0
                               
                        end
                
                        kbs = radio_enbData(11);                                            % pulls 11th element
                        kbs_txt = char(kbs);
                        test = kbs_txt;
                        if (strcmp(kbs_txt,'n/a') == 1)                                     % checks for n/a
                               kbs_txt = '0';
                               Mbs_cell = cellstr(kbs_txt);
                               radio_enbData(11) = Mbs_cell;                                % if n/a, replaces with 0
                        end
                        
                
                        timeSplit = transpose(split(radio_enbCell(1), '['));
                        timeSplit1 = transpose(split(timeSplit(2), '.'));                   % Remove millisecond from datetime.
                        radio_enbData_db = str2double(radio_enbData);                          % 'lte' should be converted to NaN
                        t_temp = datetime(timeSplit1(1), 'InputFormat', 'yyyy-MM-dd HH:mm:ss');
                        t_temp.TimeZone = 'America/New_York';
                        radio_enbData_db(1) = posixtime(t_temp);
                        
                    end
                    
                end
                radio_enbData_out = [];
                
                while ~feof(fid)
                
                    tline = fgetl(fid);
                    radio_enbCell = transpose(split(tline, ']'));                       % splits off time componet
                    radio_enbData = transpose(split(radio_enbCell(2), ' '));            % seperates elements based on spaces
                    radio_enbData = strrep(radio_enbData, '-----------------DL----------------|-------------------------UL-------------------------','');
                    radio_enbData = strrep(radio_enbData, 'rat rnti  cqi  ri  mcs  brate   ok  nok  (%) | pusch  pucch  phr  mcs  brate   ok  nok  (%)    bsr','');
                    TF = isempty(radio_enbData);
                    if TF == 0
                        if (strcmp(radio_enbData(2), 'lte') == 1)
                            radio_enbData = strrep(radio_enbData,' ','');                       % removes spaces from the elements
                            radio_enbData = radio_enbData(~cellfun('isempty',radio_enbData));   % removes empty elements from array
                            radio_enbData(10) = [];                                             % removes | character
                            kbs = radio_enbData(6);
                            kbs_txt = char(kbs);
                            test = kbs_txt(end);
                            if (strcmp(kbs_txt(end),'k') == 1)
                                kbs_txt(end) = [];
                                kbs_db = str2double(kbs_txt);
                                Mbs_db = kbs_db/1000;
                                Mbs = num2str(Mbs_db);
                                Mbs_cell6 = cellstr(Mbs);
                                radio_enbData(6) = Mbs_cell6(1);
                            elseif (strcmp(kbs_txt(end),'M') == 1)
                                kbs_txt(end) = [];
                                Mbs_cell6 = cellstr(kbs_txt);
                                radio_enbData(6) = Mbs_cell6;
                            end
                            kbs = radio_enbData(14);
                            kbs_txt = char(kbs);
                            test = kbs_txt(end);
                            if (strcmp(kbs_txt(end),'k') == 1)
                                kbs_txt(end) = [];
                                kbs_db = str2double(kbs_txt);
                                Mbs_db = kbs_db/1000;
                                Mbs = num2str(Mbs_db);
                                Mbs_cell14 = cellstr(Mbs);
                                radio_enbData(14) = Mbs_cell14(1);
                            elseif (strcmp(kbs_txt(end),'M') == 1)
                                kbs_txt(end) = [];
                                Mbs_cell14 = cellstr(kbs_txt);
                                radio_enbData(14) = Mbs_cell14;
                            end
                            kbs = radio_enbData(18);
                            kbs_txt = char(kbs);
                            test = kbs_txt(end);
                            if (strcmp(kbs_txt(end),'k') == 1)
                                kbs_txt(end) = [];
                                Mbs_cell = cellstr(kbs_txt);
                                radio_enbData(18) = Mbs_cell;
                            end
                            kbs = radio_enbData(17);
                            kbs_txt = char(kbs);
                            test = kbs_txt(end);
                            if (strcmp(kbs_txt(end),'%') == 1)
                                kbs_txt(end) = [];
                                Mbs_cell = cellstr(kbs_txt);
                                radio_enbData(17) = Mbs_cell;
                            end
                            kbs = radio_enbData(9);
                            kbs_txt = char(kbs);
                            test = kbs_txt(end);
                            if (strcmp(kbs_txt(end),'%') == 1)
                                kbs_txt(end) = [];
                                Mbs_cell = cellstr(kbs_txt);
                                radio_enbData(9) = Mbs_cell;
                            end
                
                            kbs = radio_enbData(10);
                            kbs_txt = char(kbs);
                            test = kbs_txt;
                            if (strcmp(kbs_txt,'n/a') == 1)
                                kbs_txt = '0';
                                Mbs_cell = cellstr(kbs_txt);
                                radio_enbData(10) = Mbs_cell;
                            end
                
                            kbs = radio_enbData(11);
                            kbs_txt = char(kbs);
                            test = kbs_txt;
                            if (strcmp(kbs_txt,'n/a') == 1)
                               kbs_txt = '0';
                               Mbs_cell = cellstr(kbs_txt);
                               radio_enbData(11) = Mbs_cell;
                            end
                
                            timeSplit = transpose(split(radio_enbCell(1), '['));
                            timeSplit1 = transpose(split(timeSplit(2), '.'));                   % Remove millisecond from datetime.
                            radio_enbData_db = str2double(radio_enbData);                          % 'lte' should be converted to NaN
                            t_temp = datetime(timeSplit1(1), 'InputFormat', 'yyyy-MM-dd HH:mm:ss');
                            t_temp.TimeZone = 'America/New_York';
                            time = posixtime(t_temp);
                            radio_enbData_db(1) = time;
                            %radio_enbData_out = [radio_enbData ; radio_enbData_db];
                            radio_enbData_out = [radio_enbData_out ; radio_enbData_db];
                        end
                        
                    end
                     
                end 
                radio_enbTable = array2table(radio_enbData_out, "VariableNames", ["Time" "eNB rnti" "eNB DL cqi" "eNB DL ri" ...
                    "eNB DL mcs" "eNB DL brate [Mbps]" "eNB DL ACK" "eNB DL NACK" "eNB DL NACK:ACK [%]" ...
                    "eNB UL pusch" "eNB UL pucch" "eNB UL phr" "eNB UL mcs" "eNB UL brate [Mbps]" "eNB UL ACK" ...
                    "eNB UL NACK" "eNB UL NACK:ACK [%]" "eNB UL bsr [kilo]"]);
                
                newCSVFileName = app.radioENBFile(1 : end-4);
                newCSVFileName = append(newCSVFileName, ".csv");
                writetable(radio_enbTable,append(app.radioENBPath, newCSVFileName),"Delimiter",",")
                  
                fclose(fid);

                app.lblradioENBCSV.Text = append(app.radioENBPath, newCSVFileName);
                app.radioENBComplete = true;
                app.chkbRadioENB.Value = true;
                if app.vehicleOutComplete && app.iperfServerComplete && app.iperfClientComplete && app.radioUEComplete && app.radioENBComplete && app.fileNameComplete
                    app.btnMergeFiles.Enable = true;
                end
            else
                app.lblradioENBCSV.Text = "Must select a text file first.";
                app.radioENBComplete = false;
                app.chkbRadioENB.Value = false;
            end
        end

        % Value changed function: saveAsName
        function saveAsNameValueChanged(app, event)
            value = app.saveAsName.Value;
            if length(value) > 1
                app.fileNameComplete = true;
                app.chkbFileName.Value = true;
                if app.vehicleOutComplete && app.iperfServerComplete && app.iperfClientComplete && app.radioUEComplete && app.radioENBComplete && app.fileNameComplete
                    app.btnMergeFiles.Enable = true;
                end
            else
                app.fileNameComplete = false;
                app.chkbFileName.Value = false;
            end
        end

        % Button pushed function: btnMergeFiles
        function btnMergeFilesButtonPushed(app, event)
            % CSV log file merge.
            
            % Pull in CSV files and place them in a table.
            vehicleOutTable = readtable(app.lblVehicleOutCSV.Text, 'VariableNamingRule','preserve');
            iperfServerTable = readtable(app.lblIperfServerCSV.Text, 'VariableNamingRule','preserve');
            iperfClientTable = readtable(app.lblIperfClientCSV.Text, 'VariableNamingRule', 'preserve');
            ueTable = readtable(app.lblradioUECSV.Text, 'VariableNamingRule', 'preserve');
            enbTable = readtable(app.lblradioENBCSV.Text, 'VariableNamingRule', 'preserve');
            
            % We get the start times and end times for each log file.
            vehicleTimeStart = vehicleOutTable.("Time")(1);
            vehicleTimeEnd = vehicleOutTable.("Time")(end);
            iperfServerTimeStart = iperfServerTable.("Time")(1);
            iperfServerTimeEnd = iperfServerTable.("Time")(end);
            iperfClientTimeStart = iperfClientTable.("Time")(1);
            iperfClientTimeEnd = iperfClientTable.("Time")(end);
            ueTimeStart = ueTable.("Time")(1);
            ueTimeEnd = ueTable.("Time")(end);
            eNBTimeStart = enbTable.("Time")(1);
            eNBTimeEnd = enbTable.("Time")(end);
            
            % We get the largest start time of the group and the smallest end time.
            % Future code will use max([time1 time2 ...]) and min([time1 time2 ...])
            actualTimeStart = max([iperfServerTimeStart iperfClientTimeStart vehicleTimeStart ueTimeStart eNBTimeStart]);
            actualTimeEnd = min([iperfServerTimeEnd iperfClientTimeEnd vehicleTimeEnd ueTimeEnd eNBTimeEnd]);
            %if iperfServerTimeStart > vehicleTimeStart
            %    actualTimeStart = iperfServerTimeStart;
            %else
            %    actualTimeStart = vehicleTimeStart;
            %end
            
            %if iperfServerTimeEnd < vehicleTimeEnd
            %    actualTimeEnd = iperfServerTimeEnd;
            %else
            %    actualTimeEnd = vehicleTimeEnd;
            %end
            
            % Test code at this stage to make sure correct times are selected.
            %actualTimeStart;
            %actualTimeEnd;
            
            % In this section, move all "Time" columns to be the first column of each
            % table. This is needed in the next section when creating a table with all
            % of the columns from all of the tables.
            vehicleOutTable = movevars(vehicleOutTable, "Time", 'Before', 1);
            
            % In this section, we are creating the new merged table. The table must be
            % initialized with the headers of the combined tables. The vehicle table is
            % the only table that time is kept. We will use the time in the other
            % tables to align rows, but those columns will not be kept since they are
            % redundant data.
            vehicleNames = vehicleOutTable.Properties.VariableNames;
            iperfServerNames = iperfServerTable.Properties.VariableNames;
            iperfClientNames = iperfClientTable.Properties.VariableNames;
            ueNames = ueTable.Properties.VariableNames;
            enbNames = enbTable.Properties.VariableNames;
            combinedNames = cat(2, vehicleNames, iperfServerNames(2:end), iperfClientNames(2:end), ueNames(2:end), enbNames(2:end));
            % length(combinedNames) ensures the table is created with the proper amount
            % of columns.
            mergedTable = array2table(zeros(0,length(combinedNames)));
            mergedTable.Properties.VariableNames = combinedNames;
            
            % Now that we have prepared our tables, we must sift the data to find rows
            % where all tables have the same timestamp. We know that the entries must
            % be between actualTimeStart and actualTimeEnd. So we check each time
            % between. Rows are appended if all tables contain the currentTime.
            for currentTime = actualTimeStart : actualTimeEnd
                % Each table must have a column named "Time". That column is checked
                % for the time.
                checkVehicleOut = vehicleOutTable((vehicleOutTable.Time == currentTime), :);
                emptyVehicleOut = isempty(checkVehicleOut);
                checkIperfServer = iperfServerTable((iperfServerTable.Time == currentTime), :);
                emptyIperfServer = isempty(checkIperfServer);
                checkIperfClient = iperfClientTable((iperfClientTable.Time == currentTime), :);
                emptyIperfClient = isempty(checkIperfClient);
                checkUe = ueTable((ueTable.Time == currentTime), :);
                emptyUe = isempty(checkUe);
                checkEnb = enbTable((enbTable.Time == currentTime), :);
                emptyEnb = isempty(checkEnb);
            
                % If any of the tables return an empty table row, then we must ignore
                % that row. ~isempty() returns 1 if not empty.
                if ~emptyVehicleOut && ~emptyIperfServer && ~emptyIperfClient && ~emptyUe && ~emptyEnb
                    % Only grab first row, any duplicate time stamps are ignored.
                    % "Time" is the common key used to join rows be default.
                    %disp(checkUe)
                    %disp(combinedNames)
                    joinedRow = join(checkVehicleOut(1, :), checkIperfServer(1, :), 'Keys', 'Time');
                    joinedRow = join(joinedRow(1, :), checkIperfClient(1, :), 'Keys', 'Time');
                    joinedRow = join(joinedRow(1, :), checkUe(1, :), 'Keys', 'Time');
                    joinedRow = join(joinedRow(1, :), checkEnb(1, :), 'Keys', 'Time');
                    mergedTable = [mergedTable ; joinedRow];
                end
            end
            
            mergePath = append(app.vehicleOutPath, app.saveAsName.Value);
            mergePath = append(mergePath, ".csv");

            % Data is now prepared to be converted to a new CSV file.
            writetable(mergedTable,mergePath,"Delimiter",",")

            app.lblMergePath.Text = mergePath;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'The log parser works off knowing what type of file is being parsed. For example, you must select a vehicleOut text file when using the vehicleOut parser. Selecting the wrong type will cause errors. A future update will provide a more dynmaic parser. The merge function expects five files. It must have vehicleOut, IperfServer, IperfClient, radioUE, and radioENB. I future update will make the merge script more dynamic.';
            app.btnVehicleOutPath = uibutton(app.UIFigure, 'push');
            app.btnVehicleOutPath.ButtonPushedFcn = createCallbackFcn(app, @btnVehicleOutPathButtonPushed, true);
            app.btnVehicleOutPath.Text = 'Select File';
            app.btnVehicleOutConvert = uibutton(app.UIFigure, 'push');
            app.btnVehicleOutConvert.ButtonPushedFcn = createCallbackFcn(app, @btnVehicleOutConvertButtonPushed, true);
            app.btnVehicleOutConvert.Text = 'Convert';
            app.btnIperfServerPath = uibutton(app.UIFigure, 'push');
            app.btnIperfServerPath.ButtonPushedFcn = createCallbackFcn(app, @btnIperfServerPathButtonPushed, true);
            app.btnIperfServerPath.Text = 'Select File';
            app.btnIperfServerConvert = uibutton(app.UIFigure, 'push');
            app.btnIperfServerConvert.ButtonPushedFcn = createCallbackFcn(app, @btnIperfServerConvertButtonPushed, true);
            app.btnIperfServerConvert.Text = 'Convert';
            app.btnIperfClientPath = uibutton(app.UIFigure, 'push');
            app.btnIperfClientPath.ButtonPushedFcn = createCallbackFcn(app, @btnIperfClientPathButtonPushed, true);
            app.btnIperfClientPath.Text = 'Select File';
            app.btnIperfClientConvert = uibutton(app.UIFigure, 'push');
            app.btnIperfClientConvert.ButtonPushedFcn = createCallbackFcn(app, @btnIperfClientConvertButtonPushed, true);
            app.btnIperfClientConvert.Text = 'Convert';
            app.btnradioUEPath = uibutton(app.UIFigure, 'push');
            app.btnradioUEPath.ButtonPushedFcn = createCallbackFcn(app, @btnradioUEPathButtonPushed, true);
            app.btnradioUEPath.Text = 'Select File';
            app.btnradioUEConvert = uibutton(app.UIFigure, 'push');
            app.btnradioUEConvert.ButtonPushedFcn = createCallbackFcn(app, @btnradioUEConvertButtonPushed, true);
            app.btnradioUEConvert.Text = 'Convert';
            app.btnradioENBPath = uibutton(app.UIFigure, 'push');
            app.btnradioENBPath.ButtonPushedFcn = createCallbackFcn(app, @btnradioENBPathButtonPushed, true);
            app.btnradioENBPath.Text = 'Select File';
            app.btnradioENBConvert = uibutton(app.UIFigure, 'push');
            app.btnradioENBConvert.ButtonPushedFcn = createCallbackFcn(app, @btnradioENBConvertButtonPushed, true);
            app.btnradioENBConvert.Text = 'Convert';
            app.vehicleOutLabel = uilabel(app.UIFigure);
            app.vehicleOutLabel.Text = 'vehicleOut:';
            app.IperfServerLabel = uilabel(app.UIFigure);
            app.IperfServerLabel.Text = 'IperfServer:';
            app.IperfClientLabel = uilabel(app.UIFigure);
            app.IperfClientLabel.Text = 'IperfClient:';
            app.radioUELabel = uilabel(app.UIFigure);
            app.radioUELabel.Text = 'radioUE';
            app.radioENBLabel = uilabel(app.UIFigure);
            app.radioENBLabel.Text = 'radioENB';
            app.lblVehicleOutPath = uilabel(app.UIFigure);
            app.lblVehicleOutPath.Text = '<vehicleOut file path>';
            app.lblIperfServerPath = uilabel(app.UIFigure);
            app.lblIperfServerPath.Text = '<IperfServer file path>';
            app.lblIperfClientPath = uilabel(app.UIFigure);
            app.lblIperfClientPath.Text = '<IperfClient file path>';
            app.lblradioUEPath = uilabel(app.UIFigure);
            app.lblradioUEPath.Text = '<radioUE file path>';
            app.lblradioENBPath = uilabel(app.UIFigure);
            app.lblradioENBPath.Text = '<radioENB file path>';
            app.lblVehicleOutCSV = uilabel(app.UIFigure);
            app.lblVehicleOutCSV.Text = '<csv output path>';
            app.lblIperfServerCSV = uilabel(app.UIFigure);
            app.lblIperfServerCSV.Text = '<csv output path>';
            app.lblIperfClientCSV = uilabel(app.UIFigure);
            app.lblIperfClientCSV.Text = '<csv output path>';
            app.lblradioUECSV = uilabel(app.UIFigure);
            app.lblradioUECSV.Text = '<csv output path>';
            app.lblradioENBCSV = uilabel(app.UIFigure);
            app.lblradioENBCSV.Text = '<csv output path>';
            app.chkbVehicleOut = uicheckbox(app.UIFigure);
            app.chkbVehicleOut.Enable = 'off';
            app.chkbVehicleOut.Text = 'vehicleOut';
            app.chkbIperfServer = uicheckbox(app.UIFigure);
            app.chkbIperfServer.Enable = 'off';
            app.chkbIperfServer.Text = 'iperfServer';
            app.chkbIperfClient = uicheckbox(app.UIFigure);
            app.chkbIperfClient.Enable = 'off';
            app.chkbIperfClient.Text = 'iperfClient';
            app.chkbRadioUE = uicheckbox(app.UIFigure);
            app.chkbRadioUE.Enable = 'off';
            app.chkbRadioUE.Text = 'radioUE';
            app.chkbRadioENB = uicheckbox(app.UIFigure);
            app.chkbRadioENB.Enable = 'off';
            app.chkbRadioENB.Text = 'radioENB';
            app.SaveFileAsLabel = uilabel(app.UIFigure);
            app.SaveFileAsLabel.Text = 'Save File As:';
            app.btnMergeFiles = uibutton(app.UIFigure, 'push');
            app.btnMergeFiles.ButtonPushedFcn = createCallbackFcn(app, @btnMergeFilesButtonPushed, true);
            app.btnMergeFiles.Enable = 'off';
            app.btnMergeFiles.Text = 'Merge Files';
            app.lblMergePath = uilabel(app.UIFigure);
            app.lblMergePath.Text = '< Merged CSV file path >';
            app.saveAsName = uieditfield(app.UIFigure, 'text');
            app.saveAsName.ValueChangedFcn = createCallbackFcn(app, @saveAsNameValueChanged, true);
            app.chkbFileName = uicheckbox(app.UIFigure);
            app.chkbFileName.Enable = 'off';
            app.chkbFileName.Text = 'File Name';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = log_parse_merge

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end