classdef log_merge < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        txtBoxSaveAsName          matlab.ui.control.EditField
        SaveFileAsEditFieldLabel  matlab.ui.control.Label
        listBoxMergeList          matlab.ui.control.ListBox
        listBoxFolderList         matlab.ui.control.ListBox
        FilestoMergeLabel         matlab.ui.control.Label
        FolderContentsLabel       matlab.ui.control.Label
        lblMergeFiles             matlab.ui.control.Label
        lblFolderPath             matlab.ui.control.Label
        Button_2                  matlab.ui.control.Button
        Button                    matlab.ui.control.Button
        btnMergeFiles             matlab.ui.control.Button
        btnFolderPath             matlab.ui.control.Button
    end

    
    properties (Access = public)
        folderPath; % Description
        folderSelected = false;
        currentSelFiles = string.empty % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnFolderPath
        function btnFolderPathButtonPushed(app, event)
            app.folderPath = uigetdir();
            % Check if a valid filepath. If so then populate the textbox.
            if app.folderPath ~= 0
                %app.filePath = append(path, file);
                app.lblFolderPath.Text = app.folderPath;
                app.folderSelected = true;
                
                fileList = dir(app.folderPath);
                filterList = string.empty;
                
                for i = 3 : length(fileList)
                    fileName = char(fileList(i).name);
                    extension = fileName(end - 3 : end);
                    if strcmp(extension, ".csv")
                        filterList = [filterList ; fileName]
                    end
                end
                app.listBoxFolderList.Items = filterList;
            else
                % User did not select a proper file.
                app.lblFolderPath.Text = 'Invalid File';
                app.folderSelected = false;
            end
        end

        % Button pushed function: btnMergeFiles
        function btnMergeFilesButtonPushed(app, event)
            % Need to set condition to look for 5 files.
            app.lblMergeFiles.Text = app.txtBoxSaveAsName.Value;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if ~any(strcmp(app.currentSelFiles, app.listBoxFolderList.Value))
                app.currentSelFiles = [app.currentSelFiles ; app.listBoxFolderList.Value];
                app.listBoxMergeList.Items = app.currentSelFiles;
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if any(strcmp(app.currentSelFiles, app.listBoxMergeList.Value))
                app.currentSelFiles(strcmp(app.currentSelFiles, app.listBoxMergeList.Value)) = [];
                app.listBoxMergeList.Items = app.currentSelFiles;  
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.btnFolderPath = uibutton(app.UIFigure, 'push');
            app.btnFolderPath.ButtonPushedFcn = createCallbackFcn(app, @btnFolderPathButtonPushed, true);
            app.btnFolderPath.Text = 'Filepath';
            app.btnMergeFiles = uibutton(app.UIFigure, 'push');
            app.btnMergeFiles.ButtonPushedFcn = createCallbackFcn(app, @btnMergeFilesButtonPushed, true);
            app.btnMergeFiles.Text = 'Merge';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '---->';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '<----';
            app.lblFolderPath = uilabel(app.UIFigure);
            app.lblFolderPath.Text = '< Folder path to CSV files >';
            app.lblMergeFiles = uilabel(app.UIFigure);
            app.lblMergeFiles.Text = '< File path to merged CSV file >';
            app.FolderContentsLabel = uilabel(app.UIFigure);
            app.FolderContentsLabel.Text = 'Folder Contents:';
            app.FilestoMergeLabel = uilabel(app.UIFigure);
            app.FilestoMergeLabel.Text = 'Files to Merge:';
            app.listBoxFolderList = uilistbox(app.UIFigure);
            app.listBoxFolderList.Items = {};
            app.listBoxFolderList.Value = {};
            app.listBoxMergeList = uilistbox(app.UIFigure);
            app.listBoxMergeList.Items = {};
            app.listBoxMergeList.Value = {};
            app.SaveFileAsEditFieldLabel = uilabel(app.UIFigure);
            app.SaveFileAsEditFieldLabel.Text = 'Save File As:';
            app.txtBoxSaveAsName = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = log_merge

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end