classdef parameter_plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Parameter3DPlotUIFigure  matlab.ui.Figure
        lblEventPath             matlab.ui.control.Label
        btnEventLog              matlab.ui.control.Button
        txtBoxEventLog           matlab.ui.control.TextArea
        PlotLabel_2              matlab.ui.control.Label
        parameterLabel           matlab.ui.control.Label
        pathLabel                matlab.ui.control.Label
        ParameterPathButton      matlab.ui.control.Button
        ParameterListListBox     matlab.ui.control.ListBox
        ParameterListLabel       matlab.ui.control.Label
        PlotButton               matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        filePath % Description
        eventLogPath % Path to txt file containing timestamped events.
        selectedParameter % Description
        selectedParameterBool = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if ~app.selectedParameterBool
                return
            end

            parameterList = readtable(app.filePath, 'VariableNamingRule','preserve');
            eNB_lati = 35.727479;       % Base station latitude for relative coordinates.
            eNB_long = -78.695791;      % Base station longitude for relative coordinates.
            gps_to_meters = 111139;     % Conversion from GPS coordinates to meters.

            long = parameterList.Longitude;     % Longitude [GPS]
            lati = parameterList.Latitude;      % Latitude [GPS]
            alti = parameterList.Altitude;      % Altitude [m]
            %thru = vehicleOutnew.thoughput;     % Throughput from iperf in Mbps
            parameterToPlot = parameterList.(app.parameterLabel.Text);

            long_m = (long - eNB_long)*gps_to_meters;
            lati_m = (lati - eNB_lati)*gps_to_meters;

            % Create 3D scatter plot, colorcode the values based on throughput values.

            scatter3(app.UIAxes,long_m,lati_m,alti, 40, parameterToPlot, 'filled')
            hold (app.UIAxes,'on')
            stem3(app.UIAxes,0,0,60,'filled', 'r')
            text(app.UIAxes,0,0,60,'eNB')
            title(app.UIAxes, {app.selectedParameter, 'vs. UE Location Relative to eNB'})
            xlabel(app.UIAxes,'Longitude east/west of eNB [m]')
            ylabel(app.UIAxes,'Latitude north/south of eNB [m]')
            zlabel(app.UIAxes,'Altitude [m]')
            grid (app.UIAxes,'on')
            %axis (app.UIAxes,'equal')

            % Add colorbar.
            cb = colorbar(app.UIAxes);
            %set(cb,'position',[0.9 .5 .02 .35])
            %title(cb, 'Throughput [Mbps]')
            hold (app.UIAxes,'off')
        end

        % Button pushed function: ParameterPathButton
        function ParameterPathButtonPushed(app, event)
            [file,path] = uigetfile('*.csv','Select a valid parameter csv file.','date_logtype');
            % Check if a valid filepath. If so then populate the textbox.
            if path ~= 0
                app.filePath = append(path, file);
                app.pathLabel.Text = app.filePath;
                parameterList = readtable(app.filePath, 'VariableNamingRule','preserve');
                app.ParameterListListBox.Items = parameterList.Properties.VariableNames;
            else
                % User did not select a proper file.
                app.pathLabel.Text = 'Invalid File';
            end
            %app.fileLabel.Text = file;
        end

        % Value changed function: ParameterListListBox
        function ParameterListListBoxValueChanged(app, event)
            app.selectedParameter = app.ParameterListListBox.Value;
            app.parameterLabel.Text = app.selectedParameter;
            app.selectedParameterBool = true;
        end

        % Button pushed function: btnEventLog
        function btnEventLogButtonPushed(app, event)
            [file,path] = uigetfile('*.txt','Select a valid event log text file.','thisevent.txt');
            % Check if a valid filepath. If so then populate the textbox.
            if path ~= 0
                app.eventLogPath = append(path, file);
                % Saves event log file path as global property.
                app.lblEventPath.Text = app.eventLogPath;
                % Clears the textbox if a previous log file was opened.
                app.txtBoxEventLog.Value = "Event log contents ...";
                % Code for writing text file information to text box.
                fid = fopen(app.eventLogPath); % Open event log.
                line = fgetl(fid);
                while ~feof(fid)
                    app.txtBoxEventLog.Value = [app.txtBoxEventLog.Value; line];
                    line = fgetl(fid);
                end
                app.txtBoxEventLog.Value = [app.txtBoxEventLog.Value; line];
            else
                % User did not select a proper file.
                app.lblEventPath.Text = 'Invalid File';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Parameter3DPlotUIFigure = uifigure('Visible', 'off');
            app.Parameter3DPlotUIFigure.Name = 'Parameter 3D Plot';
            app.UIAxes = uiaxes(app.Parameter3DPlotUIFigure);
            title(app.UIAxes, '<Parameter> Vs. UAV Location')
            xlabel(app.UIAxes, 'Longitude')
            ylabel(app.UIAxes, 'Latitude')
            zlabel(app.UIAxes, 'Altitude')
            subtitle(app.UIAxes, '  ')
            app.PlotButton = uibutton(app.Parameter3DPlotUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ParameterListLabel = uilabel(app.Parameter3DPlotUIFigure);
            app.ParameterListLabel.Text = 'Parameter List:';
            app.ParameterListListBox = uilistbox(app.Parameter3DPlotUIFigure);
            app.ParameterListListBox.Items = {};
            app.ParameterListListBox.ValueChangedFcn = createCallbackFcn(app, @ParameterListListBoxValueChanged, true);
            app.ParameterListListBox.Value = {};
            app.ParameterPathButton = uibutton(app.Parameter3DPlotUIFigure, 'push');
            app.ParameterPathButton.ButtonPushedFcn = createCallbackFcn(app, @ParameterPathButtonPushed, true);
            app.ParameterPathButton.Text = 'Parameter Path';
            app.pathLabel = uilabel(app.Parameter3DPlotUIFigure);
            app.pathLabel.Text = '<path>';
            app.parameterLabel = uilabel(app.Parameter3DPlotUIFigure);
            app.parameterLabel.Text = '<parameter>';
            app.PlotLabel_2 = uilabel(app.Parameter3DPlotUIFigure);
            app.PlotLabel_2.Text = 'Plot:';
            app.txtBoxEventLog = uitextarea(app.Parameter3DPlotUIFigure);
            app.txtBoxEventLog.Editable = 'off';
            app.btnEventLog = uibutton(app.Parameter3DPlotUIFigure, 'push');
            app.btnEventLog.ButtonPushedFcn = createCallbackFcn(app, @btnEventLogButtonPushed, true);
            app.btnEventLog.Text = 'Event Log';
            app.lblEventPath = uilabel(app.Parameter3DPlotUIFigure);
            app.lblEventPath.Text = '<path>';
            % Show the figure after all components are created
            app.Parameter3DPlotUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = parameter_plot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Parameter3DPlotUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Parameter3DPlotUIFigure)
        end
    end
end